classdef Rgrains < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RgrainsUIFigure                matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadsettingsMenu               matlab.ui.container.Menu
        SavesettingsMenu               matlab.ui.container.Menu
        SettingsMenu                   matlab.ui.container.Menu
        AcquisitionMenu                matlab.ui.container.Menu
        imagetypeMenu                  matlab.ui.container.Menu
        jpgMenu                        matlab.ui.container.Menu
        tifMenu                        matlab.ui.container.Menu
        pngMenu                        matlab.ui.container.Menu
        BinarisationMenu               matlab.ui.container.Menu
        UpconvertMenu                  matlab.ui.container.Menu
        IgnoreparticlesonborderMenu    matlab.ui.container.Menu
        AnalysisMenu                   matlab.ui.container.Menu
        ApplyPCDnormalisationMenu      matlab.ui.container.Menu
        CalcRoundnessMenu              matlab.ui.container.Menu
        CheckedgequalityMenu           matlab.ui.container.Menu
        UsenewCircularityMenu          matlab.ui.container.Menu
        PlotMenu                       matlab.ui.container.Menu
        BaseimageMenu                  matlab.ui.container.Menu
        ParticleboundariescolourMenu   matlab.ui.container.Menu
        MaximuminscribedcirclecolourMenu  matlab.ui.container.Menu
        CornersmallcirclescolourMenu   matlab.ui.container.Menu
        TextcolourMenu                 matlab.ui.container.Menu
        PlotfontMenu                   matlab.ui.container.Menu
        ExportMenu                     matlab.ui.container.Menu
        SavecsvMenu                    matlab.ui.container.Menu
        SaveBWimageMenu                matlab.ui.container.Menu
        SavesummaryimageMenu           matlab.ui.container.Menu
        SavefittedimagewithNumberMenu  matlab.ui.container.Menu
        SavefittedimagewithRoundnessMenu  matlab.ui.container.Menu
        SavefittredimageasvectorMenu   matlab.ui.container.Menu
        SavexmlannotationMenu          matlab.ui.container.Menu
        SaveedgeannotationMenu         matlab.ui.container.Menu
        AutoSavesettingsMenu           matlab.ui.container.Menu
        BatchMenu                      matlab.ui.container.Menu
        RunBatch                       matlab.ui.container.Menu
        ToolsMenu                      matlab.ui.container.Menu
        MergetableMenu                 matlab.ui.container.Menu
        OthersMenu                     matlab.ui.container.Menu
        VersionMenu                    matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ReplotButton                   matlab.ui.control.Button
        LoadimageButton                matlab.ui.control.Button
        BinariseButton                 matlab.ui.control.Button
        AnalyseButton                  matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        ParticleColour                 matlab.ui.control.DropDown
        BinariseMethod                 matlab.ui.control.DropDown
        BinariseSensitivity            matlab.ui.control.NumericEditField
        ImageScale                     matlab.ui.control.NumericEditField
        NoiseThresholdLower            matlab.ui.control.NumericEditField
        NoiseThresholdUpper            matlab.ui.control.NumericEditField
        Traceprecision                 matlab.ui.control.NumericEditField
        Cornersensitivity              matlab.ui.control.NumericEditField
        Circleprecision                matlab.ui.control.NumericEditField
        PCDthreshold                   matlab.ui.control.NumericEditField
        PCDthresholdpixEditFieldLabel  matlab.ui.control.Label
        CircleprecisionLabel           matlab.ui.control.Label
        CornersensitivityEditFieldLabel  matlab.ui.control.Label
        TraceprecisionLabel            matlab.ui.control.Label
        ImagescalepixcmEditFieldLabel  matlab.ui.control.Label
        SensitivityEditFieldLabel      matlab.ui.control.Label
        MethodDropDownLabel            matlab.ui.control.Label
        ParticlecolourDropDownLabel    matlab.ui.control.Label
        AcquisitionLabel               matlab.ui.control.Label
        ExportLabel                    matlab.ui.control.Label
        AnalysisLabel                  matlab.ui.control.Label
        BinarisationLabel              matlab.ui.control.Label
        NoiseThresholdLabel            matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        CurrentImageLabel              matlab.ui.control.Label
        ViewImage                      matlab.ui.control.DropDown
        MainAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        rg  = rgrains();
        opts_app = struct('base_dir',[],...
                          'save_dir',[],...
                          'in_image_type','jpg');
    end

    methods (Access = private)
   
        function updateDiplaySettings(app)
            %update app display
            %binarise options
            app.ParticleColour.Value      = app.rg.opts_binarise.particle_color;
            app.BinariseMethod.Value      = app.rg.opts_binarise.method;
            app.BinariseSensitivity.Value = app.rg.opts_binarise.adaptive_sensitivity;
            app.NoiseThresholdLower.Value = app.rg.opts_binarise.noise_thresholds(1);
            app.NoiseThresholdUpper.Value = app.rg.opts_binarise.noise_thresholds(2);
            if app.rg.opts_binarise.ignore_particles_on_borders == true
                app.IgnoreparticlesonborderMenu.Checked = "on";
            else
                app.IgnoreparticlesonborderMenu.Checked = "off";
            end
            if app.rg.opts_binarise.upconvert ==true
                app.UpconvertMenu.Checked = "on";
            else
                app.UpconvertMenu.Checked = "off";
            end
        
            %roundness options
            app.Traceprecision.Value    = app.rg.opts_roundness.trace_precision;
            app.Cornersensitivity.Value = app.rg.opts_roundness.corner_sensitivity;
            app.Circleprecision.Value    = app.rg.opts_roundness.circle_precision;
            app.ImageScale.Value        = app.rg.opts_roundness.image_scale;
            app.PCDthreshold.Value      = app.rg.opts_roundness.PCD_size;
            if app.rg.opts_roundness.PCD_normalisation==true
                app.ApplyPCDnormalisationMenu.Checked = "on";
            else
                app.ApplyPCDnormalisationMenu.Checked = "off";
            end
            if app.rg.opts_roundness.calc_roundness==true
                app.CalcRoundnessMenu.Checked = 'on';
            else
                app.CalcRoundnessMenu.Checked = 'off';
            end

            %export options
            if app.rg.opts_export.save_bw_image==true
                app.SaveBWimageMenu.Checked = "on";
            else
                app.SaveBWimageMenu.Checked = "off";
            end
            if app.rg.opts_export.save_fitted_image_with_No==true
                app.SavefittedimagewithNumberMenu.Checked = "on";
            else
                app.SavefittedimagewithNumberMenu.Checked = "off";
            end
            if app.rg.opts_export.save_fitted_image_vector==true
                app.SavefittredimageasvectorMenu.Checked = "on";
            else
                app.SavefittredimageasvectorMenu.Checked = "off";
            end
            if app.rg.opts_export.save_fitted_image_with_Roundness==true
                app.SavefittedimagewithRoundnessMenu.Checked = "on";
            else
                app.SavefittedimagewithRoundnessMenu.Checked = "off";
            end
            if app.rg.opts_export.save_summary_image==true
                app.SavesummaryimageMenu.Checked = "on";
            else
                app.SavesummaryimageMenu.Checked = "off";
            end
            if app.rg.opts_export.save_csv==true
                app.SavecsvMenu.Checked = "on";
            else
                app.SavecsvMenu.Checked = "off";
            end
            if app.rg.opts_export.save_settings==true
                app.AutoSavesettingsMenu.Checked = "on";
            else
                app.AutoSavesettingsMenu.Checked = "off";
            end
            if app.rg.opts_export.save_annotation==true
                app.SavexmlannotationMenu.Checked = "on";
            else
                app.SavexmlannotationMenu.Checked = "off";
            end
            if app.rg.opts_export.save_edge_annotation==true
                app.SaveedgeannotationMenu.Checked = "on";
            else
                app.SaveedgeannotationMenu.Checked = "off";
            end
            app.SavexmlannotationMenu.Text = strcat("Save annotation [",app.rg.opts_export.annotation_target,"]");

            %plot options
            app.BaseimageMenu.Text = strcat("Base image [",app.rg.opts_plot.base_image,"]");
            app.PlotfontMenu.Text  = strcat("Plot font [",app.rg.opts_plot.font,"]");
            app.ParticleboundariescolourMenu.ForegroundColor = app.rg.opts_plot.colour_smoothed_particle_boundaries;
            app.MaximuminscribedcirclecolourMenu.ForegroundColor = app.rg.opts_plot.colour_max_inscribed_circle;
            app.CornersmallcirclescolourMenu.ForegroundColor = app.rg.opts_plot.colour_corner_circles;
            app.TextcolourMenu.ForegroundColor = app.rg.opts_plot.colour_info_text;
        end
    
        function updatePropertySettings(app)
            %update app display
            %binarise options
            app.rg.opts_binarise.particle_color       = app.ParticleColour.Value;
            app.rg.opts_binarise.method               = app.BinariseMethod.Value;
            app.rg.opts_binarise.adaptive_sensitivity = app.BinariseSensitivity.Value;
            app.rg.opts_binarise.noise_thresholds     = [app.NoiseThresholdLower.Value, app.NoiseThresholdUpper.Value];
            switch app.IgnoreparticlesonborderMenu.Checked
                case 'on'
                    app.rg.opts_binarise.ignore_particles_on_borders = true;
                case 'off'
                    app.rg.opts_binarise.ignore_particles_on_borders = false;
            end
            switch app.UpconvertMenu.Checked
                case 'on'
                    app.rg.opts_binarise.upconvert = true;
                case 'off'
                    app.rg.opts_binarise.upconvert = false;
            end

            %roundness options
            app.rg.opts_roundness.trace_precision    = app.Traceprecision.Value;
            app.rg.opts_roundness.corner_sensitivity = app.Cornersensitivity.Value;
            app.rg.opts_roundness.circle_precision   = app.Circleprecision.Value;
            app.rg.opts_roundness.image_scale        = app.ImageScale.Value;
            app.rg.opts_roundness.PCD_size           = app.PCDthreshold.Value;
            switch  app.ApplyPCDnormalisationMenu.Checked
                case 'on'
                    app.rg.opts_roundness.PCD_normalisation = true;
                case 'off'
                    app.rg.opts_roundness.PCD_normalisation = false;
            end
            switch  app.CalcRoundnessMenu.Checked
                case 'on'
                    app.rg.opts_roundness.calc_roundness = true;
                case 'off'
                    app.rg.opts_roundness.calc_roundness = false;
            end

            %export options
            switch app.SaveBWimageMenu.Checked
                case 'on'
                    app.rg.opts_export.save_bw_image = true;
                case 'off'
                    app.rg.opts_export.save_bw_image = false;
            end
            switch app.SavefittedimagewithNumberMenu.Checked
                case 'on'
                    app.rg.opts_export.save_fitted_image_with_No = true;
                case 'off'
                    app.rg.opts_export.save_fitted_image_with_No = false;
            end
            switch app.SavefittredimageasvectorMenu.Checked
                case 'on'
                    app.rg.opts_export.save_fitted_image_vector = true;
                case 'off'
                    app.rg.opts_export.save_fitted_image_vector = false;
            end
            switch app.SavefittedimagewithRoundnessMenu.Checked
                case 'on'
                    app.rg.opts_export.save_fitted_image_with_Roundness = true;
                case 'off'
                    app.rg.opts_export.save_fitted_image_with_Roundness = false;
            end
            switch app.SavesummaryimageMenu.Checked
                case 'on'
                    app.rg.opts_export.save_summary_image = true;
                case 'off'
                    app.rg.opts_export.save_summary_image = false;
            end
            switch app.SavecsvMenu.Checked
                case 'on'
                    app.rg.opts_export.save_csv = true;
                case 'off'
                    app.rg.opts_export.save_csv = false;
            end
            switch app.AutoSavesettingsMenu.Checked
                case 'on'
                    app.rg.opts_export.save_settings = true;
                case 'off'
                    app.rg.opts_export.save_settings = false;
            end
            switch app.SavexmlannotationMenu.Checked
                case 'on'
                    app.rg.opts_export.save_annotation = true;
                case 'off'
                    app.rg.opts_export.save_annotation = false;
            end
            switch app.SaveedgeannotationMenu.Checked
                case 'on'
                    app.rg.opts_export.save_edge_annotation = true;
                case 'off'
                    app.rg.opts_export.save_edge_annotation = false;
            end
            annotation_target = strsplit(app.SavexmlannotationMenu.Text,{'[',']'});
            app.rg.opts_export.annotation_target = annotation_target{2};
            
            %plot options
            base_image = strsplit(app.BaseimageMenu.Text,{'[',']'});
            app.rg.opts_plot.base_image = base_image{2};
            plot_font = strsplit(app.PlotfontMenu.Text,{'[',']'});
            app.rg.opts_plot.font = plot_font{2};
            app.rg.opts_plot.colour_smoothed_particle_boundaries = app.ParticleboundariescolourMenu.ForegroundColor;
            app.rg.opts_plot.colour_max_inscribed_circle         = app.MaximuminscribedcirclecolourMenu.ForegroundColor;
            app.rg.opts_plot.colour_corner_circles               = app.CornersmallcirclescolourMenu.ForegroundColor;
            app.rg.opts_plot.colour_info_text                    = app.TextcolourMenu.ForegroundColor;
        end
    
        function loadSettings(app, path)
            load(path,"-mat");

            app.rg.opts_binarise  = opts_binarise;
            app.rg.opts_export    = opts_export;
            app.rg.opts_plot      = opts_plot;
            app.rg.opts_rgrains   = opts_rgrains;
            if isfield(opts_roundness,"PCD_normarisation")
                %for < v5.1.0
                opts_roundness.PCD_normalisation = opts_roundness.PCD_normarisation;
                opts_roundness = rmfield(opts_roundness,"PCD_normarisation");
            end            
            app.rg.opts_roundness = opts_roundness;
            
            app.updateDiplaySettings();
            disp("Loading settings...Done.")
        end

        function saveSettings(app, path)
            app.updatePropertySettings();
            
            opts_rgrains   = app.rg.opts_rgrains;
            opts_binarise  = app.rg.opts_binarise;
            opts_roundness = app.rg.opts_roundness;
            opts_plot      = app.rg.opts_plot;
            opts_export    = app.rg.opts_export;

            save(path,'opts_rgrains', 'opts_binarise', 'opts_roundness', 'opts_plot', 'opts_export');
            disp("Saving settings...Done.")
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %check new release---------------------------------------------
            disp('===================================================================')
            disp(strcat("Starting...",app.rg.opts_rgrains.version));
            fprintf('Checking update of Rgrains...');


            url = 'https://api.github.com/repos/keitaroyamada/Rgrains/releases/latest';

            try
                releaseInfo = webread(url);
            
                version_pattern = '\d+\.\d+(\.\d+)?';
                curVerStr = regexp(app.rg.opts_rgrains.version, version_pattern, 'match', 'once');
                webVerStr = regexp(releaseInfo.tag_name, version_pattern, 'match', 'once');
                curVerParts = split(curVerStr, '.');
                webVerParts = split(webVerStr, '.');
            
                curVer = str2double(curVerParts);
                webVer = str2double(webVerParts);
            
                if numel(curVer) < numel(webVer)
                    curVer(end+1:numel(webVer)) = 0;
                elseif numel(webVer) < numel(curVer)
                    webVer(end+1:numel(curVer)) = 0;
                end
            
                isNewer = false;
                for i = 1:numel(webVer)
                    if webVer(i) > curVer(i)
                        isNewer = true;
                        break;
                    end
                    if webVer(i) < curVer(i)
                        break;
                    end
                end
            
                if isNewer
                    fprintf('New version exist.\n');
                    q = uiconfirm(app.RgrainsUIFigure, strcat("New version (",releaseInfo.tag_name,") released. "), ...
                        "Update checker", "Options", {'Get','Afterward'});
                    if q=="Get"
                        web('https://github.com/keitaroyamada/Rgrains/releases/', '-browser');
                    end
                else
                    fprintf('Latest version is installed.\n');
                    disp("Latest Version: v" + join(string(webVer), "."))
                end
            catch e
                disp(strcat('Update check failed.'));
                disp(e);
            end

            %check requirements--------------------------------------------
            %set requirements
            rq_tools = {'MATLAB';...
                        'Curve Fitting Toolbox';...
                        'Image Processing Toolbox'};
            ver_matlab = '9.12';%2022a
            
            %set warning
            warning('off','all');

            %check matlab version
            disp('~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~')
            %disp(strcat('Starting "',app.version,'"'));
            fprintf('Checking running environment...');
            if isdeployed == 1
                fprintf('Executive version.\n')
                fprintf('Checking requirements... skipped.\n')
            else
                fprintf('Matlab version.\n')
                fprintf('Checking Matlab version...')
                V = verLessThan('MATLAB',ver_matlab );          
                if V==0
                    fprintf('OK\n');
                else
                    fprintf('CAUTION:  Matlab version is older than the required.\n');
                end
                
                %chek toolbox
                fprintf('Checking toolboxes...');
                inst_tool = struct2table(ver);
                c = find(ismember(rq_tools, inst_tool{:,1})==0);
                
                if isempty(c)
                    fprintf('OK\n');
                else
                    fprintf('CAUTION: The following required tool(s) is not installed. \n');
                    for i=1:numel(c)
                        disp(rq_tools{c(i),1});
                    end
                end
                
            end
            
            disp('===================================================================')

            %display update from rgrains default values
            app.updateDiplaySettings();

            %for next step
            app.BinariseButton.Enable = false;
            app.AnalyseButton.Enable = false;
            app.ExportButton.Enable = false;
            app.ReplotButton.Enable = false;
        end

        % Menu selected function: LoadsettingsMenu
        function LoadsettingsMenuSelected(app, event)
            [n, p] = uigetfile(fullfile(app.opts_app.base_dir,'*.rgrains'));
            
            if n==0
                return
            end
            
            app.loadSettings(fullfile(p,n));
        end

        % Menu selected function: SavesettingsMenu
        function SavesettingsMenuSelected(app, event)
            [n, p] = uiputfile(fullfile(app.opts_app.base_dir,'RgrainsSettings.rgrains'));

            app.saveSettings(fullfile(p,n));
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            if isempty(app.rg.rprops)==0
                %case instance contains calculation results
                selection = uiconfirm(app.RgrainsUIFigure, 'Unsaved calculation results will be deleted. Do you want to proceed?', 'CAUTION','Icon','warning');
                switch selection
                    case 'Cancel'
                        return;
                end
            end

            %initiarise & make new instance
            app.rg = rgrains();
            cla(app.MainAxes);

            app.updatePropertySettings();
            
            %get image path 
            [im_n, im_p] = uigetfile(fullfile(app.opts_app.base_dir,strcat('*.',app.opts_app.in_image_type)));
            if im_n ==0
                return
            end
            app.opts_app.base_dir = im_p;
            [~, im_name, ~] = fileparts(im_n);

            %load image
            app.rg.loadImage(fullfile(im_p, im_n));
            app.rg.im_name = im_name;

            %show image
            imshow(app.rg.im_in,'Parent',app.MainAxes);
            app.ViewImage.Value = 'Original';
            app.CurrentImageLabel.Text = im_name;

            %for next step
            app.BinariseButton.Enable = true;
            app.AnalyseButton.Enable = false;
            app.ExportButton.Enable = false;
            app.ReplotButton.Enable = false;
        end

        % Button pushed function: BinariseButton
        function BinariseButtonPushed(app, event)
            %binarise
            app.updatePropertySettings();%update app properties
            app.rg.opts_binarise
            app.rg.binariseImage();%binarising by rgrains

            %update plot
            app.ViewImage.Value = 'BW';
            im_bw_lb = labeloverlay(im2double(app.rg.im_in), app.rg.im_bw,'Colormap', [1,0,1], 'Transparency',0.5);
            imshow(im_bw_lb, 'Parent',app.MainAxes);

            %for next step
            app.BinariseButton.Enable = true;
            app.AnalyseButton.Enable = true;
            app.ExportButton.Enable = false;
            app.ReplotButton.Enable = false;
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
            %calculate roundness
            app.updatePropertySettings();%update app properties
            app.rg.opts_roundness
            warning('off','all')
            app.rg.calcRoundness(app.RgrainsUIFigure);
            warning('on','all')

            %update plot
            d = uiprogressdlg(app.RgrainsUIFigure,'Title','Plotting...','Indeterminate','on');
            app.ViewImage.Value = 'Results';
            app.rg.makeResultImage(app.MainAxes);

            %for next step
            app.BinariseButton.Enable = true;
            app.AnalyseButton.Enable = true;
            app.ExportButton.Enable = true;
            app.ReplotButton.Enable = true;

            close(d)
        end

        % Button pushed function: ReplotButton
        function ReplotButtonPushed(app, event)
            %update plot
            d = uiprogressdlg(app.RgrainsUIFigure,'Title','Plotting...','Indeterminate','on');
            app.updatePropertySettings();%update app properties
            app.ViewImage.Value = 'Results';
            app.rg.makeResultImage(app.MainAxes);
            close(d);
            disp("Replot result image.");
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            save_dir = uigetdir(app.opts_app.save_dir);
            if save_dir==0
                return
            end
            app.opts_app.save_dir = save_dir;
            
            %export results
            app.updatePropertySettings();%update app properties
            app.rg.opts_export%show export settings

            app.rg.export(save_dir,app.RgrainsUIFigure);
        end

        % Menu selected function: RunBatch
        function RunBatchMenuSelected(app, event)
            %get image directory
            im_dir = uigetdir(app.opts_app.base_dir,strcat('Please chose [',app.opts_app.in_image_type, '] image folder'));
            if im_dir ==0
                return
            end
            app.opts_app.base_dir = im_dir;

            %get save directory
            save_dir = uigetdir(app.opts_app.base_dir,'Please chose SAVE folder');
            if save_dir ==0
                return
            end

            %get image list
            im_list = dir(fullfile(im_dir, strcat('*.',app.opts_app.in_image_type)));
            if size(im_list,1)==0
                uiconfirm(app.RgrainsUIFigure, {'There is no image. Please chose image folder.';'Tips: Image type can be changed from settings.'},'Confirmation','Icon','warning');
                return
            end
            
            %check list
            temp_del_ids = [];
            for i=1:size(im_list,1)
                 if startsWith(im_list(i).name,'.')
                     %get ids
                     temp_del_ids = [temp_del_ids; i];
                 end
            end

            %remove file whose name starts with a dot
            im_list(temp_del_ids) = [];
            if isempty(im_list)
                uiconfirm(app.RgrainsUIFigure, {'There is no image. Please chose image folder.';'Tips: Image type can be changed from settings.'},'Confirmation','Icon','warning');
                return
            end

        
            d = uiprogressdlg(app.RgrainsUIFigure,'Title','Now processing...','Cancelable','on');
            d.CancelRequested = false;
            disp('Batch process starting...')
            disp('-------------------------------------------------------');
            disp('-------------------------------------------------------');
            tic;
            lap_time = zeros(size(im_list,1),1);
            for i =1: size(im_list,1)
                if d.CancelRequested
                    selection = uiconfirm(app.RgrainsUIFigure,'Cancel process?','Confirm Close','Icon','warning');
                    switch selection
                        case'OK'
                            break
                        case 'cancel'
                            close(d)
                            d = uiprogressdlg(app.RgrainsUIFigure,'Title','Now processing...','Cancelable','on');
                    end
                end

                d.Message = 'Initiarizing...';
                %make new instance
                app.rg = rgrains();

                %submit properties to rgrains
                app.updatePropertySettings();

                %get image path
                im_path = fullfile(im_dir, im_list(i).name);
                [~,app.rg.im_name,~]=fileparts(im_list(i).name);
                
                disp(strcat('[',num2str(i),'/',num2str(size(im_list,1)),']:',app.rg.im_name))
                d.Message =strcat('[',num2str(i),'/',num2str(size(im_list,1)),']','Calculating...',app.rg.im_name);

                %1. load image
                app.rg.loadImage(im_path);

                %2. binarise
                app.rg.binariseImage();

                %3. calc roundness
                app.rg.calcRoundness([]);

                %4. export results
                app.rg.export(save_dir);

                %progressbar
                d.Value = (i / size(im_list,1));
                RT = HMS_m((size(im_list,1)-i) * (toc/i));
                d.Title = strcat('Calculating (remaining time:',num2str(RT(1)),'h',num2str(RT(2)),'m',num2str(round(RT(3))),'s)');

                lap_time(i) = toc - sum(lap_time(1:i-1)); 
                fprintf(strcat('Lap time:',num2str(round(lap_time(i)/60,2)),'min\n'));
                disp('-------------------------------------------------------');
            end
            disp('-------------------------------------------------------');
            disp('Batch process is finished.')

            %save settings
            app.saveSettings(fullfile(save_dir,strcat('Rgrains_settings(',string(datetime("today",'Format','uuuu-MM-dd')),').rgrains')));


            %close waitbar;
            close(d);
            RT = HMS_m(toc);
            ms = strcat('All images have been analysed. Total processing time:',num2str(RT(1)),'h',num2str(RT(2)),'m',num2str(round(RT(3))),'s');
            uialert(app.RgrainsUIFigure,'Finish all process',ms,'Icon','success');
            ms = strcat('Total processing time:',num2str(RT(1)),'h',num2str(RT(2)),'m',num2str(round(RT(3))),'s');
            disp(ms);
            beep;
        end

        % Menu selected function: UpconvertMenu
        function UpconvertMenuSelected(app, event)
            switch app.UpconvertMenu.Checked
                case 'off'
                    app.UpconvertMenu.Checked = 'on';
                case 'on'
                    app.UpconvertMenu.Checked = 'off';
            end
        end

        % Menu selected function: IgnoreparticlesonborderMenu
        function IgnoreparticlesonborderMenuSelected(app, event)
            switch app.IgnoreparticlesonborderMenu.Checked
                case 'off'
                    app.IgnoreparticlesonborderMenu.Checked = 'on';
                case 'on'
                    app.IgnoreparticlesonborderMenu.Checked = 'off';
            end
        end

        % Menu selected function: ApplyPCDnormalisationMenu
        function ApplyPCDnormalisationMenuSelected(app, event)
            switch app.ApplyPCDnormalisationMenu.Checked
                case 'off'
                    app.ApplyPCDnormalisationMenu.Checked = 'on';
                    app.PCDthreshold.Enable = true;
                case 'on'
                    app.ApplyPCDnormalisationMenu.Checked = 'off';
                    app.PCDthreshold.Enable = false;
            end
        end

        % Menu selected function: CalcRoundnessMenu
        function CalcRoundnessMenuSelected(app, event)
            switch app.CalcRoundnessMenu.Checked
                case 'off'
                    app.CalcRoundnessMenu.Checked = 'on';
                    app.Traceprecision.Enable = "on";
                    app.Cornersensitivity.Enable = "on";
                    app.Circleprecision.Enable = "on";
                case 'on'
                    app.CalcRoundnessMenu.Checked = 'off';
                    app.Traceprecision.Enable = "off";
                    app.Cornersensitivity.Enable = "off";
                    app.Circleprecision.Enable = "off";
            end
        end

        % Menu selected function: CheckedgequalityMenu
        function CheckedgequalityMenuSelected(app, event)
            switch app.CheckedgequalityMenu.Checked
                case 'off'
                    app.CheckedgequalityMenu.Checked = 'on';
                    app.rg.opts_roundness.filter_inaccurate_outline = true;
                case 'on'
                    app.CheckedgequalityMenu.Checked = 'off';
                    app.rg.opts_roundness.filter_inaccurate_outline = false;
            end
        end

        % Menu selected function: UsenewCircularityMenu
        function UsenewCircularityMenuSelected(app, event)
            switch app.UsenewCircularityMenu.Checked
                case 'off'
                    app.UsenewCircularityMenu.Checked = 'on';
                    app.rg.opts_roundness.Circularity_type = 'new';
                case 'on'
                    app.UsenewCircularityMenu.Checked = 'off';
                    app.rg.opts_roundness.Circularity_type = 'legacy';
            end
        end

        % Menu selected function: SavecsvMenu
        function SavecsvMenuSelected(app, event)
            switch app.SavecsvMenu.Checked
                case 'off'
                    app.SavecsvMenu.Checked = 'on';
                case 'on'
                    app.SavecsvMenu.Checked = 'off';
            end
        end

        % Menu selected function: SaveBWimageMenu
        function SaveBWimageMenuSelected(app, event)
            switch app.SaveBWimageMenu.Checked
                case 'off'
                    app.SaveBWimageMenu.Checked = 'on';
                case 'on'
                    app.SaveBWimageMenu.Checked = 'off';
            end
        end

        % Menu selected function: SavesummaryimageMenu
        function SavesummaryimageMenuSelected(app, event)
            switch app.SavesummaryimageMenu.Checked
                case 'off'
                    app.SavesummaryimageMenu.Checked = 'on';
                case 'on'
                    app.SavesummaryimageMenu.Checked = 'off';
            end
        end

        % Menu selected function: SavefittedimagewithNumberMenu
        function SavefittedimagewithNumberMenuSelected(app, event)
            switch app.SavefittedimagewithNumberMenu.Checked
                case 'off'
                    app.SavefittedimagewithNumberMenu.Checked = 'on';
                case 'on'
                    app.SavefittedimagewithNumberMenu.Checked = 'off';
            end
        end

        % Menu selected function: SavefittedimagewithRoundnessMenu
        function SavefittedimagewithRoundnessMenuSelected(app, event)
            switch app.SavefittedimagewithRoundnessMenu.Checked
                case 'off'
                    app.SavefittedimagewithRoundnessMenu.Checked = 'on';
                case 'on'
                    app.SavefittedimagewithRoundnessMenu.Checked = 'off';
            end
        end

        % Menu selected function: SavefittredimageasvectorMenu
        function SavefittredimageasvectorMenuSelected(app, event)
            switch app.SavefittredimageasvectorMenu.Checked
                case 'off'
                    app.SavefittredimageasvectorMenu.Checked = 'on';
                case 'on'
                    app.SavefittredimageasvectorMenu.Checked = 'off';
            end
        end

        % Menu selected function: SavexmlannotationMenu
        function SavexmlannotationMenuSelected(app, event)
            switch app.SavexmlannotationMenu.Checked
                case 'off'
                    app.SavexmlannotationMenu.Checked = 'on';
                    list = ["Roundness","R","Circularity","Majorlength","Minorlength","Area","Aspect","PCD"];
                    [indx,~] = listdlg('ListString',list,'SelectionMode','single');
                    app.SavexmlannotationMenu.Text = strcat("Save annotation [",list{indx},"]");
                    app.updatePropertySettings()
                case 'on'
                    app.SavexmlannotationMenu.Checked = 'off';
            end
        end

        % Menu selected function: SaveedgeannotationMenu
        function SaveedgeannotationMenuSelected(app, event)
            switch app.SaveedgeannotationMenu.Checked
                case 'off'
                    app.SaveedgeannotationMenu.Checked = 'on';
                    app.updatePropertySettings()
                case 'on'
                    app.SaveedgeannotationMenu.Checked = 'off';
            end
        end

        % Menu selected function: AutoSavesettingsMenu
        function AutoSavesettingsMenuSelected(app, event)
            switch app.AutoSavesettingsMenu.Checked
                case 'off'
                    app.AutoSavesettingsMenu.Checked = 'on';
                case 'on'
                    app.AutoSavesettingsMenu.Checked = 'off';
            end
        end

        % Menu selected function: jpgMenu
        function jpgMenuSelected(app, event)
            switch app.jpgMenu.Checked
                case 'off'
                    app.jpgMenu.Checked = 'on';
                    app.tifMenu.Checked = 'off';
                    app.pngMenu.Checked = 'off';
                    app.opts_app.in_image_type = 'jpg';
            end
        end

        % Menu selected function: tifMenu
        function tifMenuSelected(app, event)
            switch app.jpgMenu.Checked
                case 'off'
                    app.jpgMenu.Checked = 'off';
                    app.tifMenu.Checked = 'on';
                    app.pngMenu.Checked = 'off';
                    app.opts_app.in_image_type = 'tif';
            end
        end

        % Menu selected function: pngMenu
        function pngMenuSelected(app, event)
            switch app.jpgMenu.Checked
                case 'off'
                    app.jpgMenu.Checked = 'off';
                    app.tifMenu.Checked = 'off';
                    app.pngMenu.Checked = 'on';
                    app.opts_app.in_image_type = 'png';
            end
        end

        % Value changed function: ViewImage
        function ViewImageValueChanged(app, event)
            value = app.ViewImage.Value;
            switch value
                case 'Original'
                    if isempty(app.rg.im_in)==0
                        imshow(app.rg.im_in, 'Parent',app.MainAxes);
                    else
                        app.ViewImage.Value = 'Original';
                        imshow([], 'Parent',app.MainAxes);
                    end
                case 'BW'
                    if isempty(app.rg.im_bw)==0
                        im_bw_lb = labeloverlay(im2double(app.rg.im_in), app.rg.im_bw,'Colormap', [1,0,1], 'Transparency',0.5);
                        imshow(im_bw_lb, 'Parent',app.MainAxes);
                    else
                        app.ViewImage.Value = 'Original';
                        imshow(app.rg.im_in, 'Parent',app.MainAxes);
                    end
                case 'Results'
                    if isempty(app.rg.rprops)==0
                        app.rg.makeResultImage(app.MainAxes)
                    else
                        app.ViewImage.Value = 'Original';
                        imshow(app.rg.im_in, 'Parent',app.MainAxes);
                    end
            end
        end

        % Menu selected function: VersionMenu
        function VersionMenuSelected(app, event)
            txt = strcat({'Rgrains version:',app.rg.opts_rgrains.version});
            uialert(app.RgrainsUIFigure, txt,'Version info','Icon','info');
        end

        % Menu selected function: MergetableMenu
        function MergetableMenuSelected(app, event)
            %call tool
            Rgrains_merge           
        end

        % Value changed function: BinariseMethod
        function BinariseMethodValueChanged(app, event)
            value = app.BinariseMethod.Value;
            
            switch value
                case "Adaptive"
                    app.BinariseSensitivity.Enable = true;
                case "Otsu"
                    app.BinariseSensitivity.Enable = false;
                case "Edge"
                    app.BinariseSensitivity.Enable = true;
                case "Absolute"
                    app.BinariseSensitivity.Enable = true;
                case "None"
                    app.BinariseSensitivity.Enable = false;
            end
        end

        % Menu selected function: BaseimageMenu
        function BaseimageMenuSelected(app, event)
            list      = ["original", "bw"];
            [indx,~] = listdlg('ListString',list,'SelectionMode','single');
            app.BaseimageMenu.Text = strcat("Base image [",list{indx},"]");
        end

        % Menu selected function: ParticleboundariescolourMenu
        function ParticleboundariescolourMenuSelected(app, event)
            list      = ["red","green","blue","cyan","magenta","yellow","black","white"];
            [indx,~] = listdlg('ListString',list,'SelectionMode','single');
            app.ParticleboundariescolourMenu.ForegroundColor = list{indx};
        end

        % Menu selected function: MaximuminscribedcirclecolourMenu
        function MaximuminscribedcirclecolourMenuSelected(app, event)
            list      = ["red","green","blue","cyan","magenta","yellow","black","white"];
            [indx,~] = listdlg('ListString',list,'SelectionMode','single');
            app.MaximuminscribedcirclecolourMenu.ForegroundColor = list{indx};
        end

        % Menu selected function: CornersmallcirclescolourMenu
        function CornersmallcirclescolourMenuSelected(app, event)
            list      = ["red","green","blue","cyan","magenta","yellow","black","white"];
            [indx,~] = listdlg('ListString',list,'SelectionMode','single');
            app.CornersmallcirclescolourMenu.ForegroundColor = list{indx};
        end

        % Menu selected function: TextcolourMenu
        function TextcolourMenuSelected(app, event)
            list      = ["red","green","blue","cyan","magenta","yellow","black","white"];
            [indx,~] = listdlg('ListString',list,'SelectionMode','single');
            app.TextcolourMenu.ForegroundColor = list{indx};
        end

        % Menu selected function: PlotfontMenu
        function PlotfontMenuSelected(app, event)
            list = listfonts;
            [indx,~] = listdlg('ListString',list,'SelectionMode','single');
            app.PlotfontMenu.Text = strcat("Plot font [",list{indx},"]");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RgrainsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {611, 611};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {237, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RgrainsUIFigure = uifigure('Visible', 'off');
            app.RgrainsUIFigure.Name = 'Rgrains';
            app.RgrainsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.RgrainsUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadsettingsMenu = uimenu(app.FileMenu);
            app.LoadsettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadsettingsMenuSelected, true);
            app.LoadsettingsMenu.Text = 'Load settings';
            app.SavesettingsMenu = uimenu(app.FileMenu);
            app.SavesettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SavesettingsMenuSelected, true);
            app.SavesettingsMenu.Text = 'Save settings';
            app.SettingsMenu = uimenu(app.RgrainsUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.AcquisitionMenu = uimenu(app.SettingsMenu);
            app.AcquisitionMenu.Text = 'Acquisition';
            app.imagetypeMenu = uimenu(app.AcquisitionMenu);
            app.imagetypeMenu.Text = 'image type';
            app.jpgMenu = uimenu(app.imagetypeMenu);
            app.jpgMenu.MenuSelectedFcn = createCallbackFcn(app, @jpgMenuSelected, true);
            app.jpgMenu.Checked = 'on';
            app.jpgMenu.Text = 'jpg';
            app.tifMenu = uimenu(app.imagetypeMenu);
            app.tifMenu.MenuSelectedFcn = createCallbackFcn(app, @tifMenuSelected, true);
            app.tifMenu.Text = 'tif';
            app.pngMenu = uimenu(app.imagetypeMenu);
            app.pngMenu.MenuSelectedFcn = createCallbackFcn(app, @pngMenuSelected, true);
            app.pngMenu.Text = 'png';
            app.BinarisationMenu = uimenu(app.SettingsMenu);
            app.BinarisationMenu.Text = 'Binarisation';
            app.UpconvertMenu = uimenu(app.BinarisationMenu);
            app.UpconvertMenu.MenuSelectedFcn = createCallbackFcn(app, @UpconvertMenuSelected, true);
            app.UpconvertMenu.Text = 'Upconvert';
            app.IgnoreparticlesonborderMenu = uimenu(app.BinarisationMenu);
            app.IgnoreparticlesonborderMenu.MenuSelectedFcn = createCallbackFcn(app, @IgnoreparticlesonborderMenuSelected, true);
            app.IgnoreparticlesonborderMenu.Checked = 'on';
            app.IgnoreparticlesonborderMenu.Text = 'Ignore particles on border';
            app.AnalysisMenu = uimenu(app.SettingsMenu);
            app.AnalysisMenu.Text = 'Analysis';
            app.ApplyPCDnormalisationMenu = uimenu(app.AnalysisMenu);
            app.ApplyPCDnormalisationMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplyPCDnormalisationMenuSelected, true);
            app.ApplyPCDnormalisationMenu.Checked = 'on';
            app.ApplyPCDnormalisationMenu.Text = 'Apply PCD normalisation';
            app.CalcRoundnessMenu = uimenu(app.AnalysisMenu);
            app.CalcRoundnessMenu.MenuSelectedFcn = createCallbackFcn(app, @CalcRoundnessMenuSelected, true);
            app.CalcRoundnessMenu.Checked = 'on';
            app.CalcRoundnessMenu.Text = 'Calc Roundness';
            app.CheckedgequalityMenu = uimenu(app.AnalysisMenu);
            app.CheckedgequalityMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckedgequalityMenuSelected, true);
            app.CheckedgequalityMenu.Text = 'Check edge quality';
            app.UsenewCircularityMenu = uimenu(app.AnalysisMenu);
            app.UsenewCircularityMenu.MenuSelectedFcn = createCallbackFcn(app, @UsenewCircularityMenuSelected, true);
            app.UsenewCircularityMenu.Text = 'Use new Circularity';
            app.PlotMenu = uimenu(app.SettingsMenu);
            app.PlotMenu.Text = 'Plot';
            app.BaseimageMenu = uimenu(app.PlotMenu);
            app.BaseimageMenu.MenuSelectedFcn = createCallbackFcn(app, @BaseimageMenuSelected, true);
            app.BaseimageMenu.Text = 'Base image []';
            app.ParticleboundariescolourMenu = uimenu(app.PlotMenu);
            app.ParticleboundariescolourMenu.MenuSelectedFcn = createCallbackFcn(app, @ParticleboundariescolourMenuSelected, true);
            app.ParticleboundariescolourMenu.Text = 'Particle boundaries colour';
            app.MaximuminscribedcirclecolourMenu = uimenu(app.PlotMenu);
            app.MaximuminscribedcirclecolourMenu.MenuSelectedFcn = createCallbackFcn(app, @MaximuminscribedcirclecolourMenuSelected, true);
            app.MaximuminscribedcirclecolourMenu.Text = 'Maximum inscribed circle colour';
            app.CornersmallcirclescolourMenu = uimenu(app.PlotMenu);
            app.CornersmallcirclescolourMenu.MenuSelectedFcn = createCallbackFcn(app, @CornersmallcirclescolourMenuSelected, true);
            app.CornersmallcirclescolourMenu.Text = 'Corner small circles colour';
            app.TextcolourMenu = uimenu(app.PlotMenu);
            app.TextcolourMenu.MenuSelectedFcn = createCallbackFcn(app, @TextcolourMenuSelected, true);
            app.TextcolourMenu.Text = 'Text colour';
            app.PlotfontMenu = uimenu(app.PlotMenu);
            app.PlotfontMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotfontMenuSelected, true);
            app.PlotfontMenu.Text = 'Plot font []';
            app.ExportMenu = uimenu(app.SettingsMenu);
            app.ExportMenu.Text = 'Export';
            app.SavecsvMenu = uimenu(app.ExportMenu);
            app.SavecsvMenu.MenuSelectedFcn = createCallbackFcn(app, @SavecsvMenuSelected, true);
            app.SavecsvMenu.Checked = 'on';
            app.SavecsvMenu.Text = 'Save csv';
            app.SaveBWimageMenu = uimenu(app.ExportMenu);
            app.SaveBWimageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBWimageMenuSelected, true);
            app.SaveBWimageMenu.Checked = 'on';
            app.SaveBWimageMenu.Text = 'Save BW image';
            app.SavesummaryimageMenu = uimenu(app.ExportMenu);
            app.SavesummaryimageMenu.MenuSelectedFcn = createCallbackFcn(app, @SavesummaryimageMenuSelected, true);
            app.SavesummaryimageMenu.Checked = 'on';
            app.SavesummaryimageMenu.Text = 'Save summary image';
            app.SavefittedimagewithNumberMenu = uimenu(app.ExportMenu);
            app.SavefittedimagewithNumberMenu.MenuSelectedFcn = createCallbackFcn(app, @SavefittedimagewithNumberMenuSelected, true);
            app.SavefittedimagewithNumberMenu.Checked = 'on';
            app.SavefittedimagewithNumberMenu.Text = 'Save fitted image with Number';
            app.SavefittedimagewithRoundnessMenu = uimenu(app.ExportMenu);
            app.SavefittedimagewithRoundnessMenu.MenuSelectedFcn = createCallbackFcn(app, @SavefittedimagewithRoundnessMenuSelected, true);
            app.SavefittedimagewithRoundnessMenu.Checked = 'on';
            app.SavefittedimagewithRoundnessMenu.Text = 'Save fitted image with Roundness';
            app.SavefittredimageasvectorMenu = uimenu(app.ExportMenu);
            app.SavefittredimageasvectorMenu.MenuSelectedFcn = createCallbackFcn(app, @SavefittredimageasvectorMenuSelected, true);
            app.SavefittredimageasvectorMenu.Text = 'Save fittred image as vector';
            app.SavexmlannotationMenu = uimenu(app.ExportMenu);
            app.SavexmlannotationMenu.MenuSelectedFcn = createCallbackFcn(app, @SavexmlannotationMenuSelected, true);
            app.SavexmlannotationMenu.Text = 'Save xml annotation ';
            app.SaveedgeannotationMenu = uimenu(app.ExportMenu);
            app.SaveedgeannotationMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveedgeannotationMenuSelected, true);
            app.SaveedgeannotationMenu.Text = 'Save edge annotation';
            app.AutoSavesettingsMenu = uimenu(app.ExportMenu);
            app.AutoSavesettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoSavesettingsMenuSelected, true);
            app.AutoSavesettingsMenu.Text = 'Save each image settings';
            app.BatchMenu = uimenu(app.RgrainsUIFigure);
            app.BatchMenu.Text = 'Batch';
            app.RunBatch = uimenu(app.BatchMenu);
            app.RunBatch.MenuSelectedFcn = createCallbackFcn(app, @RunBatchMenuSelected, true);
            app.RunBatch.Text = 'Run from folder';
            app.ToolsMenu = uimenu(app.RgrainsUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.MergetableMenu = uimenu(app.ToolsMenu);
            app.MergetableMenu.MenuSelectedFcn = createCallbackFcn(app, @MergetableMenuSelected, true);
            app.MergetableMenu.Text = 'Merge table';
            app.OthersMenu = uimenu(app.RgrainsUIFigure);
            app.OthersMenu.Text = 'Others';
            app.VersionMenu = uimenu(app.OthersMenu);
            app.VersionMenu.MenuSelectedFcn = createCallbackFcn(app, @VersionMenuSelected, true);
            app.VersionMenu.Text = 'Version';
            app.GridLayout = uigridlayout(app.RgrainsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NoiseThresholdLabel = uilabel(app.LeftPanel);
            app.NoiseThresholdLabel.Text = 'Noise thresholds [pix^2]';
            app.BinarisationLabel = uilabel(app.LeftPanel);
            app.BinarisationLabel.Text = '2. Binarisation';
            app.AnalysisLabel = uilabel(app.LeftPanel);
            app.AnalysisLabel.Text = '3. Analysis';
            app.ExportLabel = uilabel(app.LeftPanel);
            app.ExportLabel.Text = '4. Export';
            app.AcquisitionLabel = uilabel(app.LeftPanel);
            app.AcquisitionLabel.Text = '1. Acquisition';
            app.ParticlecolourDropDownLabel = uilabel(app.LeftPanel);
            app.ParticlecolourDropDownLabel.Text = 'Particle colour';
            app.MethodDropDownLabel = uilabel(app.LeftPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.SensitivityEditFieldLabel = uilabel(app.LeftPanel);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.ImagescalepixcmEditFieldLabel = uilabel(app.LeftPanel);
            app.ImagescalepixcmEditFieldLabel.Text = 'Image scale [pix/cm]';
            app.TraceprecisionLabel = uilabel(app.LeftPanel);
            app.TraceprecisionLabel.Text = 'Trace precision';
            app.CornersensitivityEditFieldLabel = uilabel(app.LeftPanel);
            app.CornersensitivityEditFieldLabel.Text = 'Corner sensitivity';
            app.CircleprecisionLabel = uilabel(app.LeftPanel);
            app.CircleprecisionLabel.Text = 'Circle precision';
            app.PCDthresholdpixEditFieldLabel = uilabel(app.LeftPanel);
            app.PCDthresholdpixEditFieldLabel.Text = 'PCD threshold [pix]';
            app.PCDthreshold = uieditfield(app.LeftPanel, 'numeric');
            app.PCDthreshold.Limits = [0 Inf];
            app.PCDthreshold.ValueDisplayFormat = '%.0f';
            app.PCDthreshold.Value = 300;
            app.Circleprecision = uieditfield(app.LeftPanel, 'numeric');
            app.Circleprecision.Limits = [0 1];
            app.Circleprecision.ValueDisplayFormat = '%.3f';
            app.Circleprecision.Value = 0.996;
            app.Cornersensitivity = uieditfield(app.LeftPanel, 'numeric');
            app.Cornersensitivity.Limits = [0 1];
            app.Cornersensitivity.ValueDisplayFormat = '%.3f';
            app.Cornersensitivity.Value = 0.017;
            app.Traceprecision = uieditfield(app.LeftPanel, 'numeric');
            app.Traceprecision.Limits = [0 1];
            app.Traceprecision.ValueDisplayFormat = '%.3f';
            app.Traceprecision.Value = 0.06;
            app.NoiseThresholdUpper = uieditfield(app.LeftPanel, 'numeric');
            app.NoiseThresholdUpper.Limits = [0 Inf];
            app.NoiseThresholdUpper.ValueDisplayFormat = '%.0f';
            app.NoiseThresholdUpper.Value = Inf;
            app.NoiseThresholdLower = uieditfield(app.LeftPanel, 'numeric');
            app.NoiseThresholdLower.Limits = [0 Inf];
            app.NoiseThresholdLower.ValueDisplayFormat = '%.0f';
            app.NoiseThresholdLower.Value = 490;
            app.ImageScale = uieditfield(app.LeftPanel, 'numeric');
            app.ImageScale.Limits = [0 Inf];
            app.ImageScale.ValueDisplayFormat = '%.1f';
            app.ImageScale.Value = 340;
            app.BinariseSensitivity = uieditfield(app.LeftPanel, 'numeric');
            app.BinariseSensitivity.Limits = [0 1];
            app.BinariseSensitivity.Value = 0.35;
            app.BinariseMethod = uidropdown(app.LeftPanel);
            app.BinariseMethod.Items = {'Adaptive', 'Otsu', 'Absolute', 'Edge', 'None'};
            app.BinariseMethod.ValueChangedFcn = createCallbackFcn(app, @BinariseMethodValueChanged, true);
            app.BinariseMethod.Value = 'Adaptive';
            app.ParticleColour = uidropdown(app.LeftPanel);
            app.ParticleColour.Items = {'Dark', 'Bright'};
            app.ParticleColour.Value = 'Dark';
            app.ExportButton = uibutton(app.LeftPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.AnalyseButton = uibutton(app.LeftPanel, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Enable = 'off';
            app.AnalyseButton.Text = 'Analyse';
            app.BinariseButton = uibutton(app.LeftPanel, 'push');
            app.BinariseButton.ButtonPushedFcn = createCallbackFcn(app, @BinariseButtonPushed, true);
            app.BinariseButton.Enable = 'off';
            app.BinariseButton.Text = 'Binarise';
            app.LoadimageButton = uibutton(app.LeftPanel, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.ReplotButton = uibutton(app.LeftPanel, 'push');
            app.ReplotButton.ButtonPushedFcn = createCallbackFcn(app, @ReplotButtonPushed, true);
            app.ReplotButton.Enable = 'off';
            app.ReplotButton.Text = 'Replot';
            app.RightPanel = uipanel(app.GridLayout);
            app.MainAxes = uiaxes(app.RightPanel);
            app.MainAxes.XTick = [];
            app.MainAxes.YTick = [];
            app.ViewImage = uidropdown(app.RightPanel);
            app.ViewImage.Items = {'Original', 'BW', 'Results'};
            app.ViewImage.ValueChangedFcn = createCallbackFcn(app, @ViewImageValueChanged, true);
            app.ViewImage.Value = 'Original';
            app.CurrentImageLabel = uilabel(app.RightPanel);
            app.CurrentImageLabel.Text = '...';
            % Show the figure after all components are created
            app.RgrainsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rgrains

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RgrainsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RgrainsUIFigure)
        end
    end
end