classdef Rgrains_merge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SettingsMenu      matlab.ui.container.Menu
        FiletypeMenu      matlab.ui.container.Menu
        csvMenu           matlab.ui.container.Menu
        xlsMenu           matlab.ui.container.Menu
        xlsxMenu          matlab.ui.container.Menu
        MergetableButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
       in_path  = [];
       out_path = []; % Description
       version = 'v1.1.1(20210723)';
       file_type = 'csv';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp(strcat('Marge:',app.version));
        end

        % Button pushed function: MergetableButton
        function MergetableButtonPushed(app, event)
            %get path
            [in_path]=uigetdir(app.in_path, 'Chose marge folder');
            if in_path~=0
                app.in_path = in_path;
            else
                return
            end
            [out_name, out_path]= uiputfile(fullfile(app.in_path, 'merged.csv'));
            if in_path~=0
                app.out_path = fullfile(out_path,out_name);
            else
                return
            end
            
            d = uiprogressdlg(app.UIFigure,'Title','Now processing...','Message','Getting csv list');
            %get csv list
            csv_list = dir(fullfile(app.in_path,strcat('*.',app.file_type)));
            T_csv    = struct2table(csv_list);
            
            %marge main
            T=[];
            d.Message = 'Merge data...';
            d.Value = 0;
            for i = 1: height(T_csv)
                disp(T_csv.name(i));
                t = readtable(fullfile(app.in_path, string(T_csv.name(i))));
                T=[T;t];
                d.Value = i/height(T_csv);
            end
            T(1:5,:)
            
            %save
            d.Message = 'Writting table...';
            writetable(T, app.out_path);
            close(d)

        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            switch app.csvMenu.Checked
                case 'off'
                    app.csvMenu.Checked  = 'on';
                    app.xlsMenu.Checked  = 'off';
                    app.xlsxMenu.Checked = 'off';
                    app.file_type = 'csv';
            end
        end

        % Menu selected function: xlsMenu
        function xlsMenuSelected(app, event)
            switch app.xlsMenu.Checked
                case 'off'
                    app.csvMenu.Checked  = 'off';
                    app.xlsMenu.Checked  = 'on';
                    app.xlsxMenu.Checked = 'off';
                    app.file_type = 'xls';
            end
        end

        % Menu selected function: xlsxMenu
        function xlsxMenuSelected(app, event)
            switch app.xlsxMenu.Checked
                case 'off'
                    app.csvMenu.Checked  = 'off';
                    app.xlsMenu.Checked  = 'off';
                    app.xlsxMenu.Checked = 'on';
                    app.file_type = 'xlsx';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.FiletypeMenu = uimenu(app.SettingsMenu);
            app.FiletypeMenu.Text = 'File type';
            app.csvMenu = uimenu(app.FiletypeMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Checked = 'on';
            app.csvMenu.Text = 'csv';
            app.xlsMenu = uimenu(app.FiletypeMenu);
            app.xlsMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsMenuSelected, true);
            app.xlsMenu.Text = 'xls';
            app.xlsxMenu = uimenu(app.FiletypeMenu);
            app.xlsxMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenuSelected, true);
            app.xlsxMenu.Text = 'xlsx';
            app.MergetableButton = uibutton(app.UIFigure, 'push');
            app.MergetableButton.ButtonPushedFcn = createCallbackFcn(app, @MergetableButtonPushed, true);
            app.MergetableButton.Text = 'Merge table';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rgrains_merge

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end